classdef appDiagnostico < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        TabGroup                    matlab.ui.container.TabGroup
        IniciodesesinTab            matlab.ui.container.Tab
        IniciarSesinPanel           matlab.ui.container.Panel
        Visible                     matlab.ui.control.Button
        Image2_4                    matlab.ui.control.Image
        Image2_3                    matlab.ui.control.Image
        Image2_2                    matlab.ui.control.Image
        Image2                      matlab.ui.control.Image
        IDEditField                 matlab.ui.control.EditField
        IDEditFieldLabel            matlab.ui.control.Label
        CentroEditField             matlab.ui.control.EditField
        CentroEditFieldLabel        matlab.ui.control.Label
        ContraseaEditField          matlab.ui.control.EditField
        ContraseaEditFieldLabel     matlab.ui.control.Label
        IniciarsesionButton         matlab.ui.control.Button
        Hyperlink                   matlab.ui.control.Hyperlink
        UsuarioEditField            matlab.ui.control.EditField
        UsuarioEditFieldLabel       matlab.ui.control.Label
        LineaAbajoInicio            matlab.ui.control.Label
        LogoInicio                  matlab.ui.control.Image
        EYETECHTechnologiesLabel    matlab.ui.control.Label
        LineaArribaInicio           matlab.ui.control.Label
        FondoInicio                 matlab.ui.control.Image
        OpcionesTab                 matlab.ui.container.Tab
        VolverOpciones              matlab.ui.control.Button
        MsinformacinButton          matlab.ui.control.Button
        PaginaPrincipalButton       matlab.ui.control.Button
        LineaAbajoOpciones          matlab.ui.control.Label
        LogoOpciones                matlab.ui.control.Image
        EYETECHTechnologiesAppparaeldiagnosticodeglaucomaLabel  matlab.ui.control.Label
        LineaArribaOpciones         matlab.ui.control.Label
        FondoOpciones               matlab.ui.control.Image
        InfoTab                     matlab.ui.container.Tab
        VolverInfo                  matlab.ui.control.Button
        PginawebLabel               matlab.ui.control.Label
        EtiquetaLabel               matlab.ui.control.Label
        ConcenosLabel_2             matlab.ui.control.Label
        InstruccionesLabel          matlab.ui.control.Label
        BotonConocenos              matlab.ui.control.Button
        BotonPaginaWeb              matlab.ui.control.Button
        BotonInstrucciones          matlab.ui.control.Button
        BotonEtiqueta               matlab.ui.control.Button
        LineaAbajoInfo              matlab.ui.control.Label
        LogoInfo                    matlab.ui.control.Image
        EYETECHTechnologiesAppparaeldiagnosticodeglaucomaLabel_2  matlab.ui.control.Label
        LineaArribaInfo             matlab.ui.control.Label
        FondoInfo                   matlab.ui.control.Image
        PrincipalTab                matlab.ui.container.Tab
        PositivoLamp                matlab.ui.control.Lamp
        PositivoLampLabel           matlab.ui.control.Label
        NegativoLamp                matlab.ui.control.Lamp
        NegativoLampLabel           matlab.ui.control.Label
        DignsticoLabel              matlab.ui.control.Label
        CalidadPanel                matlab.ui.container.Panel
        VolverPrincipal_4           matlab.ui.control.Button
        Segmentacion                matlab.ui.control.Button
        VolverPrincipal_6           matlab.ui.control.Button
        VolverPrincipal_3           matlab.ui.control.Button
        ImagenadiagnosticarLabel    matlab.ui.control.Label
        LogoPrincipal               matlab.ui.control.Image
        EYETECHTechnologiesLabel_2  matlab.ui.control.Label
        VolverPrincipal             matlab.ui.control.Button
        UIAxes                      matlab.ui.control.UIAxes
        SegmentacionTab             matlab.ui.container.Tab
        Panel                       matlab.ui.container.Panel
        CalidaddeimagenLabel        matlab.ui.control.Label
        DiagnsticoLamp              matlab.ui.control.Lamp
        DiagnsticoLampLabel         matlab.ui.control.Label
        SegmentacinLabel            matlab.ui.control.Label
        Image                       matlab.ui.control.Image
        ImagenadiagnosticarLabel_2  matlab.ui.control.Label
        VolverPrincipal_2           matlab.ui.control.Button
        LogoPrincipal_2             matlab.ui.control.Image
        EYETECHTechnologiesLabel_3  matlab.ui.control.Label
        UIAxes_5                    matlab.ui.control.UIAxes
        UIAxes_2                    matlab.ui.control.UIAxes
        ConcenosTab                 matlab.ui.container.Tab
        LineaAbajoInicio_2          matlab.ui.control.Label
        LogoConocenos               matlab.ui.control.Image
        EYETECHTechnologiesAppparaeldiagnosticodeglaucomaLabel_4  matlab.ui.control.Label
        LineaArribaConocenos        matlab.ui.control.Label
        VolverConocenos             matlab.ui.control.Button
        FondoConocenos              matlab.ui.control.Image
        EtiquetaTab                 matlab.ui.container.Tab
        VolverEtiqueta              matlab.ui.control.Button
        FondoEtiqueta               matlab.ui.control.Image
        InstruccionesTab            matlab.ui.container.Tab
        LineaAbajoInicio_3          matlab.ui.control.Label
        LogoInstrucciones           matlab.ui.control.Image
        EYETECHTechnologiesAppparaeldiagnosticodeglaucomaLabel_5  matlab.ui.control.Label
        LineaInstrucciones          matlab.ui.control.Label
        VolverInstrucciones         matlab.ui.control.Button
        FondoInstrucciones          matlab.ui.control.Image
        UIAxes_4                    matlab.ui.control.UIAxes
        UIAxes_3                    matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        RealText = '';  % Propiedad para almacenar el texto real
        IsTextVisible = false;
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: IniciarsesionButton
        function IniciarsesionButtonPushed(app, event)
            % Verifica si el campo de texto está vacío
            if isempty(app.UsuarioEditField.Value) 
                uialert(app.UIFigure, 'El usuario es obligatorio.', 'Advertencia');
                return;
            end
            
            if isempty(app.ContraseaEditField.Value)
                uialert(app.UIFigure, 'La contraseña es obligatoria.', 'Advertencia');
                return;
            end

            if isempty(app.CentroEditField.Value)
                uialert(app.UIFigure, 'El centro es obligatorio.', 'Advertencia');
                return;
            end

            if isempty(app.IDEditField.Value)
                uialert(app.UIFigure, 'El ID es obligatorio.', 'Advertencia');
                return;
            end 

            app.TabGroup.SelectedTab=app.OpcionesTab;

        end

        % Button pushed function: PaginaPrincipalButton
        function PaginaPrincipalButtonPushed(app, event)
            app.TabGroup.SelectedTab=app.PrincipalTab;
        end

        % Button pushed function: VolverOpciones
        function VolverOpcionesButtonPushed(app, event)
            app.TabGroup.SelectedTab=app.IniciodesesinTab;
        end

        % Button pushed function: VolverInfo
        function VolverInfoButtonPushed(app, event)
            app.TabGroup.SelectedTab=app.OpcionesTab;
        end

        % Button pushed function: MsinformacinButton
        function MsinformacinButtonPushed(app, event)
            app.TabGroup.SelectedTab=app.InfoTab;
        end

        % Button pushed function: BotonConocenos
        function BotonConocenosButtonPushed(app, event)
            app.TabGroup.SelectedTab=app.ConcenosTab;
        end

        % Button pushed function: BotonInstrucciones
        function BotonInstruccionesButtonPushed(app, event)
            app.TabGroup.SelectedTab=app.InstruccionesTab;
        end

        % Button pushed function: BotonEtiqueta
        function BotonEtiquetaButtonPushed(app, event)
            app.TabGroup.SelectedTab=app.EtiquetaTab;
        end

        % Button pushed function: BotonPaginaWeb
        function BotonPaginaWebButtonPushed(app, event)
            
        end

        % Button pushed function: VolverPrincipal
        function VolverPrincipalButtonPushed(app, event)
            app.TabGroup.SelectedTab=app.OpcionesTab;
        end

        % Button pushed function: VolverInstrucciones
        function VolverInstruccionesButtonPushed(app, event)
            app.TabGroup.SelectedTab=app.InfoTab;
        end

        % Button pushed function: VolverEtiqueta
        function VolverEtiquetaButtonPushed(app, event)
            app.TabGroup.SelectedTab=app.InfoTab;
        end

        % Button pushed function: VolverConocenos
        function VolverConocenosButtonPushed(app, event)
            app.TabGroup.SelectedTab=app.InfoTab;
        end

        % Button pushed function: VolverPrincipal_2
        function VolverPrincipal_2ButtonPushed(app, event)
            app.TabGroup.SelectedTab=app.PrincipalTab;
        end

        % Button pushed function: VolverPrincipal_3
        function VolverPrincipal_3ButtonPushed(app, event)
            % Abre un cuadro de diálogo para seleccionar una imagen
            [file, path] = uigetfile({'*.jpg;*.jpeg;*.png;*.bmp', 'Image Files (*.jpg, *.jpeg, *.png, *.bmp)'}, ...
                                     'Seleccionar Imagen');
            
            % Verifica si el usuario seleccionó un archivo
            if isequal(file, 0)
                disp('No se seleccionó ninguna imagen.');
            else
                % Muestra la ruta completa del archivo seleccionado
                fullfile_path = fullfile(path, file);
                disp(['Imagen seleccionada: ', fullfile_path]);
                
                % Lee la imagen
                img = imread(fullfile_path);
                
                % Muestra la imagen en el UIAxes
                imshow(img, 'Parent', app.UIAxes);
            end        
    
        end

        % Value changing function: ContraseaEditField
        function ContraseaEditFieldValueChanging(app, event)
        changingValue = event.Value;
                
        % Actualizar la propiedad RealText
        if length(changingValue) > length(app.RealText)
            % Se agregan carácteres
            newChar = changingValue(end);
            app.RealText = [app.RealText newChar];
        else 
        if length(changingValue) < length(app.RealText)
            % Se eliminan carácteres
            resta = length(app.RealText) - length(changingValue);
            app.RealText = app.RealText(1:end-resta);
        end
        end
        
        app.ContraseaEditField.Value = repmat('*', 1, length(changingValue));

        end

        % Value changed function: ContraseaEditField
        function ContraseaEditFieldValueChanged(app, event)
        
        end

        % Button pushed function: Visible
        function VisiblePushed(app, event)
            % Alternar la visibilidad del texto
            app.IsTextVisible = ~app.IsTextVisible;
                        
            % Actualizar el campo de texto basado en la visibilidad
            if app.IsTextVisible
                % Mostrar el texto real
                app.ContraseaEditField.Value = app.RealText;
                app.Visible.BackgroundColor = [0.5 0.5 0.5];
                app.ContraseaEditField.Editable = false;
            else
                % Ocultar el texto real con asteriscos
                app.ContraseaEditField.Value = repmat('*', 1, length(app.RealText));
                app.Visible.BackgroundColor = [0.96 0.96 0.96];
                app.ContraseaEditField.Editable = true;
            end

           
        end

        % Button pushed function: Segmentacion
        function SegmentacionButtonPushed(app, event)
             app.TabGroup.SelectedTab=app.SegmentacionTab;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes_3 = uiaxes(app.UIFigure);
            app.UIAxes_3.XTick = [];
            app.UIAxes_3.XTickLabel = '';
            app.UIAxes_3.YTick = [];
            app.UIAxes_3.YTickLabel = '';
            app.UIAxes_3.ZTick = [];
            app.UIAxes_4 = uiaxes(app.UIFigure);
            app.UIAxes_4.XTick = [];
            app.UIAxes_4.XTickLabel = '';
            app.UIAxes_4.YTick = [];
            app.UIAxes_4.YTickLabel = '';
            app.UIAxes_4.ZTick = [];
            app.TabGroup = uitabgroup(app.UIFigure);
            app.IniciodesesinTab = uitab(app.TabGroup);
            app.IniciodesesinTab.Title = 'Inicio de sesión';
            app.FondoInicio = uiimage(app.IniciodesesinTab);
            app.FondoInicio.ImageSource = 'textura-abstracta-azul.jpg';
            app.LineaArribaInicio = uilabel(app.IniciodesesinTab);
            app.LineaArribaInicio.Text = 'h';
            app.EYETECHTechnologiesLabel = uilabel(app.IniciodesesinTab);
            app.EYETECHTechnologiesLabel.Text = 'EYETECH Technologies';
            app.LogoInicio = uiimage(app.IniciodesesinTab);
            app.LogoInicio.ImageSource = 'Imagen1.png';
            app.LineaAbajoInicio = uilabel(app.IniciodesesinTab);
            app.IniciarSesinPanel = uipanel(app.IniciodesesinTab);
            app.IniciarSesinPanel.Title = 'Iniciar Sesión';
            app.UsuarioEditFieldLabel = uilabel(app.IniciarSesinPanel);
            app.UsuarioEditFieldLabel.Text = 'Usuario';
            app.UsuarioEditField = uieditfield(app.IniciarSesinPanel, 'text');
            app.Hyperlink = uihyperlink(app.IniciarSesinPanel);
            app.Hyperlink.Text = 'Registrarse';
            app.IniciarsesionButton = uibutton(app.IniciarSesinPanel, 'push');
            app.IniciarsesionButton.ButtonPushedFcn = createCallbackFcn(app, @IniciarsesionButtonPushed, true);
            app.IniciarsesionButton.Text = 'Iniciar sesion';
            app.ContraseaEditFieldLabel = uilabel(app.IniciarSesinPanel);
            app.ContraseaEditFieldLabel.Text = 'Contraseña';
            app.ContraseaEditField = uieditfield(app.IniciarSesinPanel, 'text');
            app.ContraseaEditField.ValueChangedFcn = createCallbackFcn(app, @ContraseaEditFieldValueChanged, true);
            app.ContraseaEditField.ValueChangingFcn = createCallbackFcn(app, @ContraseaEditFieldValueChanging, true);
            app.CentroEditFieldLabel = uilabel(app.IniciarSesinPanel);
            app.CentroEditFieldLabel.Text = 'Centro';
            app.CentroEditField = uieditfield(app.IniciarSesinPanel, 'text');
            app.IDEditFieldLabel = uilabel(app.IniciarSesinPanel);
            app.IDEditFieldLabel.Text = 'ID ';
            app.IDEditField = uieditfield(app.IniciarSesinPanel, 'text');
            app.Image2 = uiimage(app.IniciarSesinPanel);
            app.Image2.ImageSource = 'asterisco rojo.png';
            app.Image2_2 = uiimage(app.IniciarSesinPanel);
            app.Image2_2.ImageSource = 'asterisco rojo.png';
            app.Image2_3 = uiimage(app.IniciarSesinPanel);
            app.Image2_3.ImageSource = 'asterisco rojo.png';
            app.Image2_4 = uiimage(app.IniciarSesinPanel);
            app.Image2_4.ImageSource = 'asterisco rojo.png';
            app.Visible = uibutton(app.IniciarSesinPanel, 'push');
            app.Visible.ButtonPushedFcn = createCallbackFcn(app, @VisiblePushed, true);
            app.Visible.Icon = 'ojo.png';
            app.Visible.Text = '';
            app.OpcionesTab = uitab(app.TabGroup);
            app.OpcionesTab.Title = 'Opciones';
            app.FondoOpciones = uiimage(app.OpcionesTab);
            app.FondoOpciones.ImageSource = 'textura-abstracta-azul.jpg';
            app.LineaArribaOpciones = uilabel(app.OpcionesTab);
            app.LineaArribaOpciones.Text = 'h';
            app.EYETECHTechnologiesAppparaeldiagnosticodeglaucomaLabel = uilabel(app.OpcionesTab);
            app.EYETECHTechnologiesAppparaeldiagnosticodeglaucomaLabel.Text = 'EYETECH Technologies - App para el diagnostico de glaucoma';
            app.LogoOpciones = uiimage(app.OpcionesTab);
            app.LogoOpciones.ImageSource = 'Imagen1.png';
            app.LineaAbajoOpciones = uilabel(app.OpcionesTab);
            app.PaginaPrincipalButton = uibutton(app.OpcionesTab, 'push');
            app.PaginaPrincipalButton.ButtonPushedFcn = createCallbackFcn(app, @PaginaPrincipalButtonPushed, true);
            app.PaginaPrincipalButton.Text = 'Pagina Principal';
            app.MsinformacinButton = uibutton(app.OpcionesTab, 'push');
            app.MsinformacinButton.ButtonPushedFcn = createCallbackFcn(app, @MsinformacinButtonPushed, true);
            app.MsinformacinButton.Text = 'Más información';
            app.VolverOpciones = uibutton(app.OpcionesTab, 'push');
            app.VolverOpciones.ButtonPushedFcn = createCallbackFcn(app, @VolverOpcionesButtonPushed, true);
            app.VolverOpciones.Text = 'Volver';
            app.InfoTab = uitab(app.TabGroup);
            app.InfoTab.Title = 'Info';
            app.FondoInfo = uiimage(app.InfoTab);
            app.FondoInfo.ImageSource = 'textura-abstracta-azul.jpg';
            app.LineaArribaInfo = uilabel(app.InfoTab);
            app.LineaArribaInfo.Text = 'h';
            app.EYETECHTechnologiesAppparaeldiagnosticodeglaucomaLabel_2 = uilabel(app.InfoTab);
            app.EYETECHTechnologiesAppparaeldiagnosticodeglaucomaLabel_2.Text = 'EYETECH Technologies - App para el diagnostico de glaucoma';
            app.LogoInfo = uiimage(app.InfoTab);
            app.LogoInfo.ImageSource = 'Imagen1.png';
            app.LineaAbajoInfo = uilabel(app.InfoTab);
            app.BotonEtiqueta = uibutton(app.InfoTab, 'push');
            app.BotonEtiqueta.ButtonPushedFcn = createCallbackFcn(app, @BotonEtiquetaButtonPushed, true);
            app.BotonEtiqueta.Icon = 'etiqueta.png';
            app.BotonEtiqueta.Text = '';
            app.BotonInstrucciones = uibutton(app.InfoTab, 'push');
            app.BotonInstrucciones.ButtonPushedFcn = createCallbackFcn(app, @BotonInstruccionesButtonPushed, true);
            app.BotonInstrucciones.Icon = 'ImagenInstrucciones.png';
            app.BotonInstrucciones.Text = '';
            app.BotonPaginaWeb = uibutton(app.InfoTab, 'push');
            app.BotonPaginaWeb.ButtonPushedFcn = createCallbackFcn(app, @BotonPaginaWebButtonPushed, true);
            app.BotonPaginaWeb.Icon = 'web.jpeg';
            app.BotonPaginaWeb.Text = '';
            app.BotonConocenos = uibutton(app.InfoTab, 'push');
            app.BotonConocenos.ButtonPushedFcn = createCallbackFcn(app, @BotonConocenosButtonPushed, true);
            app.BotonConocenos.Icon = 'Imagen1.png';
            app.BotonConocenos.Text = '';
            app.InstruccionesLabel = uilabel(app.InfoTab);
            app.InstruccionesLabel.Text = 'Instrucciones';
            app.ConcenosLabel_2 = uilabel(app.InfoTab);
            app.ConcenosLabel_2.Text = 'Conócenos';
            app.EtiquetaLabel = uilabel(app.InfoTab);
            app.EtiquetaLabel.Text = 'Etiqueta';
            app.PginawebLabel = uilabel(app.InfoTab);
            app.PginawebLabel.Text = 'Página web';
            app.VolverInfo = uibutton(app.InfoTab, 'push');
            app.VolverInfo.ButtonPushedFcn = createCallbackFcn(app, @VolverInfoButtonPushed, true);
            app.VolverInfo.Text = 'Volver';
            app.PrincipalTab = uitab(app.TabGroup);
            app.PrincipalTab.Title = 'Principal';
            app.UIAxes = uiaxes(app.PrincipalTab);
            app.UIAxes.XTick = [];
            app.UIAxes.XTickLabel = '';
            app.UIAxes.YTick = [];
            app.UIAxes.YTickLabel = '';
            app.UIAxes.ZTick = [];
            app.VolverPrincipal = uibutton(app.PrincipalTab, 'push');
            app.VolverPrincipal.ButtonPushedFcn = createCallbackFcn(app, @VolverPrincipalButtonPushed, true);
            app.VolverPrincipal.Text = 'Volver';
            app.EYETECHTechnologiesLabel_2 = uilabel(app.PrincipalTab);
            app.EYETECHTechnologiesLabel_2.Text = 'EYETECH Technologies ';
            app.LogoPrincipal = uiimage(app.PrincipalTab);
            app.LogoPrincipal.ImageSource = 'Imagen1.png';
            app.ImagenadiagnosticarLabel = uilabel(app.PrincipalTab);
            app.ImagenadiagnosticarLabel.Text = 'Imagen a diagnosticar';
            app.VolverPrincipal_3 = uibutton(app.PrincipalTab, 'push');
            app.VolverPrincipal_3.ButtonPushedFcn = createCallbackFcn(app, @VolverPrincipal_3ButtonPushed, true);
            app.VolverPrincipal_3.Text = 'Cargar Imagen';
            app.VolverPrincipal_6 = uibutton(app.PrincipalTab, 'push');
            app.VolverPrincipal_6.Text = 'Mostrar calidad';
            app.Segmentacion = uibutton(app.PrincipalTab, 'push');
            app.Segmentacion.ButtonPushedFcn = createCallbackFcn(app, @SegmentacionButtonPushed, true);
            app.Segmentacion.Text = 'Segmentación';
            app.VolverPrincipal_4 = uibutton(app.PrincipalTab, 'push');
            app.VolverPrincipal_4.Text = 'Diagnóstico';
            app.CalidadPanel = uipanel(app.PrincipalTab);
            app.CalidadPanel.Title = 'Calidad';
            app.DignsticoLabel = uilabel(app.PrincipalTab);
            app.DignsticoLabel.Text = 'Dignóstico';
            app.NegativoLampLabel = uilabel(app.PrincipalTab);
            app.NegativoLampLabel.Text = 'Negativo';
            app.NegativoLamp = uilamp(app.PrincipalTab);
            app.PositivoLampLabel = uilabel(app.PrincipalTab);
            app.PositivoLampLabel.Text = 'Positivo';
            app.PositivoLamp = uilamp(app.PrincipalTab);
            app.SegmentacionTab = uitab(app.TabGroup);
            app.SegmentacionTab.Title = 'Segmentacion';
            app.UIAxes_2 = uiaxes(app.SegmentacionTab);
            app.UIAxes_2.XTick = [];
            app.UIAxes_2.XTickLabel = '';
            app.UIAxes_2.YTick = [];
            app.UIAxes_2.YTickLabel = '';
            app.UIAxes_2.ZTick = [];
            app.UIAxes_5 = uiaxes(app.SegmentacionTab);
            app.UIAxes_5.XTick = [];
            app.UIAxes_5.XTickLabel = '';
            app.UIAxes_5.YTick = [];
            app.UIAxes_5.YTickLabel = '';
            app.UIAxes_5.ZTick = [];
            app.EYETECHTechnologiesLabel_3 = uilabel(app.SegmentacionTab);
            app.EYETECHTechnologiesLabel_3.Text = 'EYETECH Technologies ';
            app.LogoPrincipal_2 = uiimage(app.SegmentacionTab);
            app.LogoPrincipal_2.ImageSource = 'Imagen1.png';
            app.VolverPrincipal_2 = uibutton(app.SegmentacionTab, 'push');
            app.VolverPrincipal_2.ButtonPushedFcn = createCallbackFcn(app, @VolverPrincipal_2ButtonPushed, true);
            app.VolverPrincipal_2.Text = 'Volver';
            app.ImagenadiagnosticarLabel_2 = uilabel(app.SegmentacionTab);
            app.ImagenadiagnosticarLabel_2.Text = 'Imagen a diagnosticar';
            app.Image = uiimage(app.SegmentacionTab);
            app.Image.ImageSource = 'Flecha.png';
            app.SegmentacinLabel = uilabel(app.SegmentacionTab);
            app.SegmentacinLabel.Text = 'Segmentación';
            app.Panel = uipanel(app.SegmentacionTab);
            app.DiagnsticoLampLabel = uilabel(app.Panel);
            app.DiagnsticoLampLabel.Text = 'Diagnóstico';
            app.DiagnsticoLamp = uilamp(app.Panel);
            app.CalidaddeimagenLabel = uilabel(app.Panel);
            app.CalidaddeimagenLabel.Text = 'Calidad de imagen';
            app.ConcenosTab = uitab(app.TabGroup);
            app.ConcenosTab.Title = 'Conócenos';
            app.FondoConocenos = uiimage(app.ConcenosTab);
            app.FondoConocenos.ImageSource = 'textura-abstracta-azul.jpg';
            app.VolverConocenos = uibutton(app.ConcenosTab, 'push');
            app.VolverConocenos.ButtonPushedFcn = createCallbackFcn(app, @VolverConocenosButtonPushed, true);
            app.VolverConocenos.Text = 'Volver';
            app.LineaArribaConocenos = uilabel(app.ConcenosTab);
            app.LineaArribaConocenos.Text = 'h';
            app.EYETECHTechnologiesAppparaeldiagnosticodeglaucomaLabel_4 = uilabel(app.ConcenosTab);
            app.EYETECHTechnologiesAppparaeldiagnosticodeglaucomaLabel_4.Text = 'EYETECH Technologies - App para el diagnostico de glaucoma';
            app.LogoConocenos = uiimage(app.ConcenosTab);
            app.LogoConocenos.ImageSource = 'Imagen1.png';
            app.LineaAbajoInicio_2 = uilabel(app.ConcenosTab);
            app.EtiquetaTab = uitab(app.TabGroup);
            app.EtiquetaTab.Title = 'Etiqueta';
            app.FondoEtiqueta = uiimage(app.EtiquetaTab);
            app.FondoEtiqueta.ImageSource = 'EtiquetaSinAcabar.png';
            app.VolverEtiqueta = uibutton(app.EtiquetaTab, 'push');
            app.VolverEtiqueta.ButtonPushedFcn = createCallbackFcn(app, @VolverEtiquetaButtonPushed, true);
            app.VolverEtiqueta.Text = 'Volver';
            app.InstruccionesTab = uitab(app.TabGroup);
            app.InstruccionesTab.Title = 'Instrucciones';
            app.FondoInstrucciones = uiimage(app.InstruccionesTab);
            app.FondoInstrucciones.ImageSource = 'textura-abstracta-azul.jpg';
            app.VolverInstrucciones = uibutton(app.InstruccionesTab, 'push');
            app.VolverInstrucciones.ButtonPushedFcn = createCallbackFcn(app, @VolverInstruccionesButtonPushed, true);
            app.VolverInstrucciones.Text = 'Volver';
            app.LineaInstrucciones = uilabel(app.InstruccionesTab);
            app.LineaInstrucciones.Text = 'h';
            app.EYETECHTechnologiesAppparaeldiagnosticodeglaucomaLabel_5 = uilabel(app.InstruccionesTab);
            app.EYETECHTechnologiesAppparaeldiagnosticodeglaucomaLabel_5.Text = 'EYETECH Technologies - App para el diagnostico de glaucoma';
            app.LogoInstrucciones = uiimage(app.InstruccionesTab);
            app.LogoInstrucciones.ImageSource = 'Imagen1.png';
            app.LineaAbajoInicio_3 = uilabel(app.InstruccionesTab);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = appDiagnostico

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end