classdef appDiagnostico < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        TabGroup                    matlab.ui.container.TabGroup
        IniciodesesinTab            matlab.ui.container.Tab
        IniciarSesinPanel           matlab.ui.container.Panel
        RegistroButton              matlab.ui.control.Button
        Visible                     matlab.ui.control.Button
        Asterisco4                  matlab.ui.control.Image
        Asterisco3                  matlab.ui.control.Image
        Asterisco2                  matlab.ui.control.Image
        Asterisco1                  matlab.ui.control.Image
        IDEditField                 matlab.ui.control.EditField
        IDEditFieldLabel            matlab.ui.control.Label
        CentroEditField             matlab.ui.control.EditField
        CentroEditFieldLabel        matlab.ui.control.Label
        ContraseaEditField          matlab.ui.control.EditField
        ContraseaEditFieldLabel     matlab.ui.control.Label
        IniciarsesionButton         matlab.ui.control.Button
        UsuarioEditField            matlab.ui.control.EditField
        UsuarioEditFieldLabel       matlab.ui.control.Label
        LineaAbajoInicio            matlab.ui.control.Label
        LogoInicio                  matlab.ui.control.Image
        EYETECHTechnologiesLabel    matlab.ui.control.Label
        LineaArribaInicio           matlab.ui.control.Label
        FondoInicio                 matlab.ui.control.Image
        OpcionesTab                 matlab.ui.container.Tab
        Tel34632254112CorreoelectrnicoeyetechgmailcomLabel  matlab.ui.control.Label
        VolverOpciones              matlab.ui.control.Button
        MsinformacinButton          matlab.ui.control.Button
        PaginaPrincipalButton       matlab.ui.control.Button
        LineaAbajoOpciones          matlab.ui.control.Label
        LogoOpciones                matlab.ui.control.Image
        EYETECHTechnologiesAppparaeldiagnosticodeglaucomaLabel  matlab.ui.control.Label
        LineaArribaOpciones         matlab.ui.control.Label
        FondoOpciones               matlab.ui.control.Image
        InfoTab                     matlab.ui.container.Tab
        Tel34632254112CorreoelectrnicoeyetechgmailcomLabel_2  matlab.ui.control.Label
        DeclaracionLabel            matlab.ui.control.Label
        BotonDeclaracion            matlab.ui.control.Button
        VolverInfo                  matlab.ui.control.Button
        PginawebLabel               matlab.ui.control.Label
        EtiquetaLabel               matlab.ui.control.Label
        ConocenosLabel              matlab.ui.control.Label
        InstruccionesLabel          matlab.ui.control.Label
        BotonConocenos              matlab.ui.control.Button
        BotonPaginaWeb              matlab.ui.control.Button
        BotonInstrucciones          matlab.ui.control.Button
        BotonEtiqueta               matlab.ui.control.Button
        LineaAbajoInfo              matlab.ui.control.Label
        LogoInfo                    matlab.ui.control.Image
        EYETECHTechnologiesAppparaeldiagnosticodeglaucomaLabel_2  matlab.ui.control.Label
        LineaArribaInfo             matlab.ui.control.Label
        FondoInfo                   matlab.ui.control.Image
        PrincipalTab                matlab.ui.container.Tab
        LineaAbajoInicio_4          matlab.ui.control.Label
        LamparaDiagnostico          matlab.ui.control.Lamp
        DignsticoLabel              matlab.ui.control.Label
        CalidadPanel                matlab.ui.container.Panel
        PorcentajeCalidad           matlab.ui.control.Label
        PorcentajeAC                matlab.ui.control.Label
        PorcentajeR                 matlab.ui.control.Label
        PorcentajeD                 matlab.ui.control.Label
        PorcentajeBC                matlab.ui.control.Label
        LamparaCalidad              matlab.ui.control.Lamp
        CalidadLabel                matlab.ui.control.Label
        AltaClaridadLabel           matlab.ui.control.Label
        RuidoLabel                  matlab.ui.control.Label
        DesenfoqueLabel_2           matlab.ui.control.Label
        BajoContrasteLabel          matlab.ui.control.Label
        DiagnosticoBoton            matlab.ui.control.Button
        Segmentacion                matlab.ui.control.Button
        CalidadImagen               matlab.ui.control.Button
        CargarImagen                matlab.ui.control.Button
        ImagenadiagnosticarLabel    matlab.ui.control.Label
        LogoPrincipal               matlab.ui.control.Image
        EYETECHTechnologiesLabel_2  matlab.ui.control.Label
        VolverPrincipal             matlab.ui.control.Button
        UIAxes                      matlab.ui.control.UIAxes
        SegmentacionTab             matlab.ui.container.Tab
        ROIamanoButton              matlab.ui.control.StateButton
        Panel                       matlab.ui.container.Panel
        CalidaddeimagenLabel_2      matlab.ui.control.Label
        CalidaddeimagenLabel        matlab.ui.control.Label
        DiagnsticoLamp              matlab.ui.control.Lamp
        DiagnsticoLampLabel         matlab.ui.control.Label
        SegmentacinLabel            matlab.ui.control.Label
        VolverSegmentacion          matlab.ui.control.Button
        Image                       matlab.ui.control.Image
        ImagenadiagnosticarLabel_2  matlab.ui.control.Label
        LogoPrincipal_2             matlab.ui.control.Image
        EYETECHTechnologiesLabel_3  matlab.ui.control.Label
        UIAxes_5                    matlab.ui.control.UIAxes
        UIAxes_2                    matlab.ui.control.UIAxes
        ConcenosTab                 matlab.ui.container.Tab
        ContactoLabel               matlab.ui.control.Label
        ContactoBoton               matlab.ui.control.Button
        ParticipantesLabel          matlab.ui.control.Label
        ParticipantesBoton          matlab.ui.control.Button
        Image3                      matlab.ui.control.Image
        Image2                      matlab.ui.control.Image
        Tel34632254112CorreoelectrnicoeyetechgmailcomLabel_3  matlab.ui.control.Label
        LineaAbajoInicio_2          matlab.ui.control.Label
        LogoConocenos               matlab.ui.control.Image
        EYETECHTechnologiesAppparaeldiagnosticodeglaucomaLabel_4  matlab.ui.control.Label
        LineaArribaConocenos        matlab.ui.control.Label
        VolverConocenos             matlab.ui.control.Button
        FondoConocenos              matlab.ui.control.Image
        NosotrosTab                 matlab.ui.container.Tab
        Image4                      matlab.ui.control.Image
        VolverNosotros              matlab.ui.control.Button
        Tel34632254112CorreoelectrnicoeyetechgmailcomLabel_4  matlab.ui.control.Label
        LineaAbajoInicio_5          matlab.ui.control.Label
        LogoConocenos_2             matlab.ui.control.Image
        EYETECHTechnologiesAppparaeldiagnosticodeglaucomaLabel_5  matlab.ui.control.Label
        LineaArribaConocenos_2      matlab.ui.control.Label
        FondoConocenos_2            matlab.ui.control.Image
    end

    
    properties (Access = private)
        RealText = '';  % Propiedad para almacenar el texto real
        IsTextVisible = false;
        img
        ROI
        Calidad
        Mdl
        Glaucoma
        points
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
           app.Mdl = modelo_final;
        end

        % Button pushed function: IniciarsesionButton
        function IniciarsesionButtonPushed(app, event)
            % Verifica si el campo de texto está vacío
            if isempty(app.UsuarioEditField.Value) || isempty(app.ContraseaEditField.Value) || isempty(app.CentroEditField.Value) || isempty(app.IDEditField.Value)
                uialert(app.UIFigure, 'Se deben rellenar todas las casillas.', 'Advertencia');
                return;
            end
            
            app.TabGroup.SelectedTab=app.OpcionesTab;

        end

        % Button pushed function: PaginaPrincipalButton
        function PaginaPrincipalButtonPushed(app, event)
            app.TabGroup.SelectedTab=app.PrincipalTab;
        end

        % Button pushed function: VolverOpciones
        function VolverOpcionesButtonPushed(app, event)
            app.TabGroup.SelectedTab=app.IniciodesesinTab;
        end

        % Button pushed function: VolverInfo
        function VolverInfoButtonPushed(app, event)
            app.TabGroup.SelectedTab=app.OpcionesTab;
        end

        % Button pushed function: MsinformacinButton
        function MsinformacinButtonPushed(app, event)
            app.TabGroup.SelectedTab=app.InfoTab;
        end

        % Button pushed function: BotonConocenos
        function BotonConocenosButtonPushed(app, event)
            app.TabGroup.SelectedTab=app.ConcenosTab;
        end

        % Button pushed function: BotonInstrucciones
        function BotonInstruccionesButtonPushed(app, event)
            % Path to the PDF file
             pdfFilePath = 'C:\Users\Joane Legarreta\Desktop\PBL6\App\Interfaz\Instrucciones.pdf';  % Especifica la ruta completa del archivo PDF
             % Open the PDF file
             if ispc  % Para sistemas Windows
             system(['start "" "' pdfFilePath '"']);
             elseif ismac  % Para sistemas macOS
             system(['open "' pdfFilePath '"']);
             elseif isunix  % Para sistemas Linux
             system(['xdg-open "' pdfFilePath '"']);
             else
             uialert(app.UIFigure, 'Platform not supported', 'Error');
             end
        end

        % Button pushed function: BotonEtiqueta
        function BotonEtiquetaButtonPushed(app, event)
            
            % Path to the PDF file
             pdfFilePath = 'C:\Users\Joane Legarreta\Desktop\PBL6\App\Interfaz\Etiqueta_final.pdf';  % Especifica la ruta completa del archivo PDF
             % Open the PDF file
             if ispc  % Para sistemas Windows
             system(['start "" "' pdfFilePath '"']);
             elseif ismac  % Para sistemas macOS
             system(['open "' pdfFilePath '"']);
             elseif isunix  % Para sistemas Linux
             system(['xdg-open "' pdfFilePath '"']);
             else
             uialert(app.UIFigure, 'Platform not supported', 'Error');
             end

        end

        % Button pushed function: BotonPaginaWeb
        function BotonPaginaWebButtonPushed(app, event)
            % URL de la página web
            url = 'https://joanelegarreta.github.io/PBL_grupo_1/';  
            % Abrir la página web
            web(url, '-browser');
        end

        % Button pushed function: VolverPrincipal
        function VolverPrincipalButtonPushed(app, event)
            app.TabGroup.SelectedTab=app.OpcionesTab;
        end

        % Button pushed function: VolverConocenos
        function VolverConocenosButtonPushed(app, event)
            app.TabGroup.SelectedTab=app.InfoTab;
        end

        % Button pushed function: VolverSegmentacion
        function VolverSegmentacionButtonPushed(app, event)
            app.TabGroup.SelectedTab=app.PrincipalTab;
        end

        % Button pushed function: CargarImagen
        function CargarImagenButtonPushed(app, event)
            % Abre un cuadro de diálogo para seleccionar una imagen
            [file, path] = uigetfile({'*.jpg;*.jpeg;*.png;*.bmp', 'Image Files (*.jpg, *.jpeg, *.png, *.bmp)'}, ...
                                     'Seleccionar Imagen');
            
            % Verifica si el usuario seleccionó un archivo
            if isequal(file, 0)
                disp('No se seleccionó ninguna imagen.');
            else
                % Muestra la ruta completa del archivo seleccionado
                fullfile_path = fullfile(path, file);
                disp(['Imagen seleccionada: ', fullfile_path]);
                
                % Lee la imagen
                app.img = imread(fullfile_path);
                
                % Muestra la imagen en el UIAxes
                imshow(app.img, 'Parent', app.UIAxes);
            end  

            %Reestablecer la tabla que informa de la calidad de la imagen y
            %la lampara del diagnostico
            app.PorcentajeBC.Text = '%';
            app.PorcentajeR.Text = '%';
            app.PorcentajeAC.Text = '%';
            app.PorcentajeD.Text = '%';
            app.PorcentajeCalidad.Text = '%';
            app.Calidad = '';
            app.CalidaddeimagenLabel.Text = 'Calidad';
            app.LamparaCalidad.Color = [0, 0, 1];  % Lámpara azul
            app.LamparaDiagnostico.Color = [0, 0, 1]; %Lámpara azul
            app.DiagnsticoLamp.Color = [0, 0, 1];% Lámpara azul        

            % Calcular el ROI
            I = app.img;
            sizes = [2424 3004];
            pixels = sizes(1)*sizes(2);
            sizes_ROI = [801 801];
            radius = 1100;
            n = 400;
            
            % create mask to hide borders
            center = [sizes(2)/2, sizes(1)/2];
            I3 = zeros(sizes(1:2));
            [x, y] = meshgrid(1:sizes(2), 1:sizes(1));
            I3((x - center(1)).^2 + (y - center(2)).^2 <= radius^2) = 1;
            I3 = uint8(I3);
                                     
            I = imresize(I,sizes);
            I2 = I(:,:,2); % get green channel
            I2 = I2 .* I3; % apply masc   
            
            [~, indices] = sort(I2(:), 'descend');
            top = indices(1:round(pixels*0.0015)); % get brightest 0.15% of pixels
            
            [posiciones_y, posiciones_x] = ind2sub(size(I2), top);
        
            meanx = round(mean(posiciones_x));
            meany = round(mean(posiciones_y));
            
            app.ROI = I(max(1,meany-n):min(sizes(1),meany+n),max(1, meanx-n):min(sizes(2),meanx+n),:);
            app.points = [max(1,meany-n),min(sizes(1),meany+n),max(1, meanx-n),min(sizes(2),meanx+n)];

            if sum(size(app.ROI) ~= [801 801 3])>0
                app.ROI = imresize(app.ROI,sizes_ROI); 
            end


        end

        % Value changing function: ContraseaEditField
        function ContraseaEditFieldValueChanging(app, event)
        changingValue = event.Value;
                
        % Actualizar la propiedad RealText
        if length(changingValue) > length(app.RealText)
            % Se agregan carácteres
            newChar = changingValue(end);
            app.RealText = [app.RealText newChar];
        else 
        if length(changingValue) < length(app.RealText)
            % Se eliminan carácteres
            resta = length(app.RealText) - length(changingValue);
            app.RealText = app.RealText(1:end-resta);
        end
        end
        
        app.ContraseaEditField.Value = repmat('*', 1, length(changingValue));

        end

        % Value changed function: ContraseaEditField
        function ContraseaEditFieldValueChanged(app, event)
        
        end

        % Button pushed function: Visible
        function VisiblePushed(app, event)
            % Alternar la visibilidad del texto
            app.IsTextVisible = ~app.IsTextVisible;
                        
            % Actualizar el campo de texto basado en la visibilidad
            if app.IsTextVisible
                % Mostrar el texto real
                app.ContraseaEditField.Value = app.RealText;
                app.Visible.BackgroundColor = [0.5 0.5 0.5];
                app.ContraseaEditField.Editable = false;
            else
                % Ocultar el texto real con asteriscos
                app.ContraseaEditField.Value = repmat('*', 1, length(app.RealText));
                app.Visible.BackgroundColor = [0.96 0.96 0.96];
                app.ContraseaEditField.Editable = true;
            end

           
        end

        % Button pushed function: Segmentacion
        function SegmentacionButtonPushed(app, event)
            if isempty(app.UIAxes.Children)
            uialert(app.UIFigure, 'Agregue una imagen antes.', 'Advertencia');
                return;
            elseif   isempty(app.Calidad)  
            uialert(app.UIFigure, 'Califique la calidad de la imagen previamente.', 'Advertencia');
                return;
            elseif app.Calidad <= 60
            uialert(app.UIFigure, 'Debe mejorar la calidad de su imagen para poder segmentarla', 'Advertencia');
                return; 
            else    
                
                app.TabGroup.SelectedTab = app.SegmentacionTab;
    
                I = app.ROI;
                I_g = im2double(rgb2gray(I));
                sizes_ROI = [801 801];
                sizes = [2424 3004];
                pixels = sizes(1)*sizes(2);

                I1 = I(:,:,1);
                I2 = I(:,:,2);
                I3 = I(:,:,3);
                 
                I1 = adapthisteq(I1);
                I2 = adapthisteq(I2);
                I3 = adapthisteq(I3);
                
                m1 = double([max(max(I1)) min(min(I1)) mean2(I1) std2(I1)]);
                m2 = double([max(max(I2)) min(min(I2)) mean2(I2) std2(I2)]);
                m3 = double([max(max(I3)) min(min(I3)) mean2(I3) std2(I3)]);
                
                threshold = [min([0.99 m1(3)*1.2/255]),m2(3)*1.3/255,m3(3)*1.2/255];
                
                IB1 = imbinarize(I1,threshold(1));
                IB2 = imbinarize(I2,threshold(2));
                IB3 = imbinarize(I3,threshold(3));
                
                contraste = stdfilt(IB1); 
                cont(1) = sum(contraste(:));
                contraste = stdfilt(IB2); 
                cont(2) = sum(contraste(:));
                contraste = stdfilt(IB3); 
                cont(3) = sum(contraste(:));
            
                pixelesEnComun = sum(IB2(:) & IB1(:));
                p1 = (pixelesEnComun / max(sum(IB2(:)==1),sum(IB1(:)==1))) * 100;   
                pixelesEnComun = sum(IB2(:) & IB3(:));
                p3 = (pixelesEnComun / max(sum(IB2(:)==1),sum(IB3(:)==1))) * 100;
                
                I4 = ones(sizes_ROI(1), sizes_ROI(2));
                
                if m1(3)<180 || sum(IB1(:))<pixels*0.4 && cont(1)<4*10^4 && sum(IB1(:))>pixels*0.1  && p1>10
                    I4 = I4 .* IB1;
                end
                
                if m2(3)>30 && sum(IB2(:))<pixels*0.4 && cont(2)<4*10^4
                    I4 = I4 .* IB2;
                end
                
                if m3(3)>30 && sum(IB3(:))<pixels*0.4 && cont(3)<4*10^4 && p3>10
                    I4 = I4 .* IB3;        
                end
                
                see = strel('disk',20);
                see2 = strel('disk',5);
                
                I4 = imclose(I4,see2);
                I5 = imerode(I4,see2);
                I6 = imdilate(I5,see);
                
                I6 = imbinarize(I6,0);
                
                I7 = activecontour(I_g, I6, 'Chan-vese');
                I8 = activecontour(I_g, I6, 'edge');
                I9 = I7+I8;
                I9 = imbinarize(I9,0);
                
                Disc = bwareafilt(I9,3);
                Disc = imfill(Disc, 'holes');
                            
                [posiciones_y, posiciones_x] = ind2sub(size(Disc), find(Disc==1));                
                centro_x1 = round(mean(posiciones_x));
                centro_y1 = round(mean(posiciones_y));
                
                radio_maximo = 300;
                angulos = linspace(0, 2*pi, 20);
                                
                x_border = zeros(1,length(angulos));
                y_border = zeros(1,length(angulos));
                ultimo_radio = zeros(1,length(angulos));
                
                for i = 1:length(angulos)
                    x_final = round(centro_x1 + radio_maximo * cos(angulos(i)));
                    y_final = round(centro_y1 + radio_maximo * sin(angulos(i)));
                
                    perfil = improfile(Disc, [centro_x1, x_final], [centro_y1, y_final],radio_maximo);
                    indices_blancos = find(perfil > 0);
                
                    if isempty(indices_blancos)
                        ultimo_radio(i) = radio_maximo;
                    else
                        ultimo_radio(i) = indices_blancos(end);    
                    end
                
                    x_border(i) = round(centro_x1 + ultimo_radio(i) * cos(angulos(i)));
                    y_border(i) = round(centro_y1 + ultimo_radio(i) * sin(angulos(i)));
                end
                   
                distancia_media = mean(ultimo_radio);
                
                for i = 2:length(angulos)
                    if abs(ultimo_radio(i)-ultimo_radio(i-1))>100
                        if abs(ultimo_radio(i)-distancia_media)<abs(ultimo_radio(i-1)-distancia_media)
                                x_border(i-1) = round(centro_x1 + ultimo_radio(i) * cos(angulos(i-1)));
                                y_border(i-1) = round(centro_y1 + ultimo_radio(i) * sin(angulos(i-1)));
                                ultimo_radio(i-1) = ultimo_radio(i);
                        else
                                x_border(i) = round(centro_x1 + ultimo_radio(i-1) * cos(angulos(i)));
                                y_border(i) = round(centro_y1 + ultimo_radio(i-1) * sin(angulos(i)));
                                ultimo_radio(i) = ultimo_radio(i-1);
                        end
                    end
                end
                
                centro_x2 = round(mean(x_border));
                centro_y2 = round(mean(y_border));
                distancia_media = mean(ultimo_radio);
                
                threshold2 = m2(3)*0.6/255;
                IB2 = imcomplement(imbinarize(I2,threshold2));
                
                IB2 = imdilate(IB2,strel('disk',10));
                
                radius = distancia_media;
                I3 = zeros(sizes_ROI(1:2));
                [x, y] = meshgrid(1:sizes_ROI(2), 1:sizes_ROI(1));
                I3((x - centro_x2).^2 + (y - centro_y2).^2 <= radius^2) = 1;
                
                IB2 = IB2 .* (I3);
                Disc = Disc+IB2;
                
                Disc = imbinarize(Disc,0);
                Disc = bwareafilt(Disc,1);
                Disc = imfill(Disc, 'holes');
                
                for i = 1:length(angulos)
                    x_final = round(centro_x1 + radio_maximo * cos(angulos(i)));
                    y_final = round(centro_y1 + radio_maximo * sin(angulos(i)));
                
                    perfil = improfile(Disc, [centro_x1, x_final], [centro_y1, y_final],radio_maximo);
                    indices_blancos = find(perfil > 0);
                
                    if isempty(indices_blancos)
                        ultimo_radio(i)=300;
                    else
                        ultimo_radio(i) = indices_blancos(end);    
                    end
                
                    x_border(i) = round(centro_x1 + ultimo_radio(i) * cos(angulos(i)));
                    y_border(i) = round(centro_y1 + ultimo_radio(i) * sin(angulos(i)));
                end
                
                border = I;
                for i =1:length(x_border)
                    border(max(1,y_border(i)-5):min(sizes_ROI(1),y_border(i)+5), max(1,x_border(i)-5):min(sizes_ROI(1),x_border(i)+5),1)=0;
                    border(max(1,y_border(i)-5):min(sizes_ROI(1),y_border(i)+5), max(1,x_border(i)-5):min(sizes_ROI(1),x_border(i)+5),2)=0;
                    border(max(1,y_border(i)-5):min(sizes_ROI(1),y_border(i)+5), max(1,x_border(i)-5):min(sizes_ROI(1),x_border(i)+5),3)=255;
                end

                x_points = [app.points(3) app.points(4) app.points(4) app.points(3) app.points(3)];
                y_points = [app.points(1) app.points(1) app.points(2) app.points(2) app.points(1)];                
                cla(app.UIAxes_2);
                I = imresize(app.img,sizes);

                imshow(I, 'Parent', app.UIAxes_2);
                hold(app.UIAxes_2,'on');
                plot(x_points,y_points,'Parent', app.UIAxes_2, 'Color', 'white', 'LineWidth',2)

                cla(app.UIAxes_5);
                imshow(border, 'Parent', app.UIAxes_5);
            end
        end

        % Button pushed function: CalidadImagen
        function CalidadImagenButtonPushed(app, event)
            if isempty(app.UIAxes.Children)
            uialert(app.UIFigure, 'Agregue una imagen antes.', 'Advertencia');
                return;
            else
            I = imload(app.img);     
            contraste = stdfilt(I);
            cont = sum(contraste(:));
            SD = std2(I(:));
            
            SD_range = [0.07 0.16 0.22 0.3];
            cont_range = [6.5*10^3 8.5*10^3 10^5 1.8*10^6];
            
            if SD_range(1) <= SD && SD <= SD_range(2)
                bajo_contraste = 100-round(100*(SD-SD_range(1))/(SD_range(2)-SD_range(1)));
            elseif SD_range(1) > SD
                bajo_contraste = 100;
            else
                bajo_contraste = 0;
            end
            
                       
            if cont_range(1) <= cont && cont <= cont_range(2)
                desenfoque = 100-round(100*(cont-cont_range(1))/(cont_range(2)-cont_range(1)));
            elseif cont_range(1) > cont
                desenfoque = 100;
            else
                desenfoque = 0;
            end


            if cont_range(3) <= cont && cont <= cont_range(4)
                ruido = round(100*(cont-cont_range(3))/(cont_range(4)-cont_range(3)));
            elseif cont_range(3) > cont
                ruido = 0;
            else
                ruido = 100;
            end


            if SD_range(3) <= SD && SD <= SD_range(4)
                muy_claras =  round(100*(SD-SD_range(3))/(SD_range(4)-SD_range(3)));
            elseif SD_range(3) > SD
                muy_claras = 0;
            else
                muy_claras = 100;
            end
            
            buena_calidad = 100-max([bajo_contraste desenfoque ruido muy_claras]);
           
            if buena_calidad >= 60 
            app.LamparaCalidad.Color = [0, 1, 0];  % Lámpara verde 
            else
            app.LamparaCalidad.Color = [1, 0, 0];  % Lámpara roja 
            end    
            
            app.Calidad=buena_calidad;

            bajo_contraste=num2str(bajo_contraste);
            app.PorcentajeBC.Text = [ bajo_contraste '%'];

            desenfoque = num2str(desenfoque);
            app.PorcentajeD.Text = [ desenfoque '%'];

            ruido=num2str(ruido);
            app.PorcentajeR.Text = [ ruido '%'];

            muy_claras = num2str(muy_claras);
            app.PorcentajeAC.Text = [ muy_claras '%'];
            
            buena_calidad = num2str(buena_calidad);
            app.PorcentajeCalidad.Text = [ buena_calidad '%'];
            app.CalidaddeimagenLabel.Text = [buena_calidad '%'];
                     
           end 
            
            function I = imload(file)
               I = rgb2gray(file);             % escala de grises
               I = imresize(I,[2424 3004]);    % tamaño
               I = im2double(I);               % normalization y fomrato double
           end
           
        
        end

        % Button pushed function: DiagnosticoBoton
        function DiagnosticoBotonButtonPushed(app, event)
            
            if isempty(app.UIAxes.Children)
            uialert(app.UIFigure, 'Agregue una imagen antes.', 'Advertencia');
                return;
            elseif   isempty(app.Calidad)  
            uialert(app.UIFigure, 'Califique la calidad de la imagen previamente.', 'Advertencia');
                return;
            elseif app.Calidad <= 60
            uialert(app.UIFigure, 'Debe mejorar la calidad de su imagen para poder hacer un diagnóstico', 'Advertencia');
                return; 
            else            
            
            % Get the features
                Feature_ROI = rgb2gray(app.ROI);
                sn = waveletScattering2('ImageSize',[300 300],'InvarianceScale',125,'QualityFactors',[1 1],'NumRotations',[6 6]); 
                features = ScatImages_mean(sn,Feature_ROI);

            % Predict usign the model
                [app.Glaucoma, ~] = predict(app.Mdl, features');
                                
                if app.Glaucoma == 1
                    app.LamparaDiagnostico.Color = [1, 0, 0];  % Lámpara rojo
                else
                    app.LamparaDiagnostico.Color = [0, 1, 0];  % Lámpara verde
                end

               if app.Glaucoma == 1
                    app.DiagnsticoLamp.Color = [1, 0, 0];  % Lámpara rojo
                else
                    app.DiagnsticoLamp.Color = [0, 1, 0];  % Lámpara verde
                end
            
            end
        end

        % Button pushed function: BotonDeclaracion
        function BotonDeclaracionButtonPushed(app, event)
             
            % Path to the PDF file
             pdfFilePath = 'Declaración UE de conformidad.pdf';  % Especifica la ruta completa del archivo PDF
             % Open the PDF file
             if ispc  % Para sistemas Windows
             system(['start "" "' pdfFilePath '"']);
             elseif ismac  % Para sistemas macOS
             system(['open "' pdfFilePath '"']);
             elseif isunix  % Para sistemas Linux
             system(['xdg-open "' pdfFilePath '"']);
             else
             uialert(app.UIFigure, 'Platform not supported', 'Error');
             end
             
        end

        % Value changed function: CentroEditField
        function CentroEditFieldValueChanged(app, event)
            value = app.CentroEditField.Value;
                      
            % Dividir el texto en palabras usando espacios
            words = strsplit(value);
    
            % Lista de palabras permitidas
            allowedWords = {'Txagorritxu', 'Alto Deba', 'Cruces', 'Hospital Universitario Donostia'};
    
            % Verificar si todas las palabras son permitidas
            isValid = all(ismember(words, allowedWords));
    
            if ~isValid
                % Mostrar una advertencia al usuario
                uialert(app.UIFigure, 'Solo se permiten las palabras: Txagorritxu, Alto Deba, Cruces, Hospital Universitario Donostia.', 'Advertencia');
                % Limpiar el texto del cuadro de texto
                app.CentroEditField.Value = '';
                return;
            end
        
        end

        % Value changed function: IDEditField
        function IDEditFieldValueChanged(app, event)
            value = app.IDEditField.Value;
             % Expresión regular para validar el formato "123A"
             pattern = '^\d{3}[A-Za-z]$';
    
             % Verificar si el texto cumple con el patrón
             isValid = ~isempty(regexp(value, pattern, 'once'));
    
             if ~isValid
             % Mostrar una advertencia al usuario
             uialert(app.UIFigure, 'El texto debe ser tres números seguidos de una letra (por ejemplo, "123A").', 'Advertencia');
             % Limpiar el texto del cuadro de texto
             
             app.IDEditField.Value = '';
             return;
             end
        end

        % Button pushed function: ContactoBoton
        function ContactoBotonButtonPushed(app, event)
            % URL de la página web
            url2 = 'https://joanelegarreta.github.io/PBL_grupo_1/Contacto.html';  
            % Abrir la página web
            web(url2, '-browser');
        end

        % Button pushed function: ParticipantesBoton
        function ParticipantesBotonButtonPushed(app, event)
            app.TabGroup.SelectedTab=app.NosotrosTab;
        end

        % Button pushed function: VolverNosotros
        function VolverNosotrosButtonPushed(app, event)
            app.TabGroup.SelectedTab=app.ConcenosTab;
        end

        % Button pushed function: RegistroButton
        function RegistroButtonPushed(app, event)
            % URL de la página web
            url3 = 'https://joanelegarreta.github.io/PBL_grupo_1/Registrar.html';  
            % Abrir la página web
            web(url3, '-browser');
        end

        % Value changed function: ROIamanoButton
        function ROIamanoButtonValueChanged(app, event)
            [x_ROI, y_ROI] = selectDataPoints(app.UIAxes_2);
            
            meanx = round(mean(x_ROI));
            meany = round(mean(y_ROI));
     
            sizes = [2424 3004];
            n = 400;
            I = imresize(app.img,sizes);

            app.ROI = I(max(1,meany-n):min(sizes(1),meany+n),max(1, meanx-n):min(sizes(2),meanx+n),:);
            app.points = [max(1,meany-n),min(sizes(1),meany+n),max(1, meanx-n),min(sizes(2),meanx+n)];

            if sum(size(app.ROI) ~= [801 801 3])>0
                app.ROI = imresize(app.ROI,sizes_ROI); 
            end

            SegmentacionButtonPushed(app, event)

            function [x, y] = selectDataPoints(ax)
                roi = drawpoint(ax);
                x = roi.Position(1);
                y = roi.Position(2);
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.IniciodesesinTab = uitab(app.TabGroup);
            app.IniciodesesinTab.Title = 'Inicio de sesión';
            app.FondoInicio = uiimage(app.IniciodesesinTab);
            app.FondoInicio.ImageSource = 'textura-abstracta-azul.jpg';
            app.LineaArribaInicio = uilabel(app.IniciodesesinTab);
            app.LineaArribaInicio.Text = 'h';
            app.EYETECHTechnologiesLabel = uilabel(app.IniciodesesinTab);
            app.EYETECHTechnologiesLabel.Text = 'EYETECH Technologies';
            app.LogoInicio = uiimage(app.IniciodesesinTab);
            app.LogoInicio.ImageSource = 'Imagen1.png';
            app.LineaAbajoInicio = uilabel(app.IniciodesesinTab);
            app.IniciarSesinPanel = uipanel(app.IniciodesesinTab);
            app.IniciarSesinPanel.Title = 'Iniciar Sesión';
            app.UsuarioEditFieldLabel = uilabel(app.IniciarSesinPanel);
            app.UsuarioEditFieldLabel.Text = 'Usuario';
            app.UsuarioEditField = uieditfield(app.IniciarSesinPanel, 'text');
            app.IniciarsesionButton = uibutton(app.IniciarSesinPanel, 'push');
            app.IniciarsesionButton.ButtonPushedFcn = createCallbackFcn(app, @IniciarsesionButtonPushed, true);
            app.IniciarsesionButton.Text = 'Iniciar sesion';
            app.ContraseaEditFieldLabel = uilabel(app.IniciarSesinPanel);
            app.ContraseaEditFieldLabel.Text = 'Contraseña';
            app.ContraseaEditField = uieditfield(app.IniciarSesinPanel, 'text');
            app.ContraseaEditField.ValueChangedFcn = createCallbackFcn(app, @ContraseaEditFieldValueChanged, true);
            app.ContraseaEditField.ValueChangingFcn = createCallbackFcn(app, @ContraseaEditFieldValueChanging, true);
            app.CentroEditFieldLabel = uilabel(app.IniciarSesinPanel);
            app.CentroEditFieldLabel.Text = 'Centro';
            app.CentroEditField = uieditfield(app.IniciarSesinPanel, 'text');
            app.CentroEditField.ValueChangedFcn = createCallbackFcn(app, @CentroEditFieldValueChanged, true);
            app.IDEditFieldLabel = uilabel(app.IniciarSesinPanel);
            app.IDEditFieldLabel.Text = 'ID ';
            app.IDEditField = uieditfield(app.IniciarSesinPanel, 'text');
            app.IDEditField.ValueChangedFcn = createCallbackFcn(app, @IDEditFieldValueChanged, true);
            app.Asterisco1 = uiimage(app.IniciarSesinPanel);
            app.Asterisco1.ImageSource = 'asterisco rojo.png';
            app.Asterisco2 = uiimage(app.IniciarSesinPanel);
            app.Asterisco2.ImageSource = 'asterisco rojo.png';
            app.Asterisco3 = uiimage(app.IniciarSesinPanel);
            app.Asterisco3.ImageSource = 'asterisco rojo.png';
            app.Asterisco4 = uiimage(app.IniciarSesinPanel);
            app.Asterisco4.ImageSource = 'asterisco rojo.png';
            app.Visible = uibutton(app.IniciarSesinPanel, 'push');
            app.Visible.ButtonPushedFcn = createCallbackFcn(app, @VisiblePushed, true);
            app.Visible.Icon = 'ojo.png';
            app.Visible.Text = '';
            app.RegistroButton = uibutton(app.IniciarSesinPanel, 'push');
            app.RegistroButton.ButtonPushedFcn = createCallbackFcn(app, @RegistroButtonPushed, true);
            app.RegistroButton.Text = 'Registro';
            app.OpcionesTab = uitab(app.TabGroup);
            app.OpcionesTab.Title = 'Opciones';
            app.FondoOpciones = uiimage(app.OpcionesTab);
            app.FondoOpciones.ImageSource = 'textura-abstracta-azul.jpg';
            app.LineaArribaOpciones = uilabel(app.OpcionesTab);
            app.LineaArribaOpciones.Text = 'h';
            app.EYETECHTechnologiesAppparaeldiagnosticodeglaucomaLabel = uilabel(app.OpcionesTab);
            app.EYETECHTechnologiesAppparaeldiagnosticodeglaucomaLabel.Text = 'EYETECH Technologies - App para el diagnostico de glaucoma';
            app.LogoOpciones = uiimage(app.OpcionesTab);
            app.LogoOpciones.ImageSource = 'Imagen1.png';
            app.LineaAbajoOpciones = uilabel(app.OpcionesTab);
            app.PaginaPrincipalButton = uibutton(app.OpcionesTab, 'push');
            app.PaginaPrincipalButton.ButtonPushedFcn = createCallbackFcn(app, @PaginaPrincipalButtonPushed, true);
            app.PaginaPrincipalButton.Text = 'Pagina Principal';
            app.MsinformacinButton = uibutton(app.OpcionesTab, 'push');
            app.MsinformacinButton.ButtonPushedFcn = createCallbackFcn(app, @MsinformacinButtonPushed, true);
            app.MsinformacinButton.Text = 'Más información';
            app.VolverOpciones = uibutton(app.OpcionesTab, 'push');
            app.VolverOpciones.ButtonPushedFcn = createCallbackFcn(app, @VolverOpcionesButtonPushed, true);
            app.VolverOpciones.Text = 'Volver';
            app.Tel34632254112CorreoelectrnicoeyetechgmailcomLabel = uilabel(app.OpcionesTab);
            app.Tel34632254112CorreoelectrnicoeyetechgmailcomLabel.Text = 'Tel.: +34 632 254 112   /    Correo electrónico: eyetech@gmail.com';
            app.InfoTab = uitab(app.TabGroup);
            app.InfoTab.Title = 'Info';
            app.FondoInfo = uiimage(app.InfoTab);
            app.FondoInfo.ImageSource = 'textura-abstracta-azul.jpg';
            app.LineaArribaInfo = uilabel(app.InfoTab);
            app.LineaArribaInfo.Text = 'h';
            app.EYETECHTechnologiesAppparaeldiagnosticodeglaucomaLabel_2 = uilabel(app.InfoTab);
            app.EYETECHTechnologiesAppparaeldiagnosticodeglaucomaLabel_2.Text = 'EYETECH Technologies - App para el diagnostico de glaucoma';
            app.LogoInfo = uiimage(app.InfoTab);
            app.LogoInfo.ImageSource = 'Imagen1.png';
            app.LineaAbajoInfo = uilabel(app.InfoTab);
            app.BotonEtiqueta = uibutton(app.InfoTab, 'push');
            app.BotonEtiqueta.ButtonPushedFcn = createCallbackFcn(app, @BotonEtiquetaButtonPushed, true);
            app.BotonEtiqueta.Icon = 'etiqueta.png';
            app.BotonEtiqueta.Text = '';
            app.BotonInstrucciones = uibutton(app.InfoTab, 'push');
            app.BotonInstrucciones.ButtonPushedFcn = createCallbackFcn(app, @BotonInstruccionesButtonPushed, true);
            app.BotonInstrucciones.Icon = 'ImagenInstrucciones.png';
            app.BotonInstrucciones.Text = '';
            app.BotonPaginaWeb = uibutton(app.InfoTab, 'push');
            app.BotonPaginaWeb.ButtonPushedFcn = createCallbackFcn(app, @BotonPaginaWebButtonPushed, true);
            app.BotonPaginaWeb.Icon = 'web.jpeg';
            app.BotonPaginaWeb.Text = '';
            app.BotonConocenos = uibutton(app.InfoTab, 'push');
            app.BotonConocenos.ButtonPushedFcn = createCallbackFcn(app, @BotonConocenosButtonPushed, true);
            app.BotonConocenos.Icon = 'Imagen1.png';
            app.BotonConocenos.Text = '';
            app.InstruccionesLabel = uilabel(app.InfoTab);
            app.InstruccionesLabel.Text = 'Instrucciones';
            app.ConocenosLabel = uilabel(app.InfoTab);
            app.ConocenosLabel.Text = 'Conócenos';
            app.EtiquetaLabel = uilabel(app.InfoTab);
            app.EtiquetaLabel.Text = 'Etiqueta';
            app.PginawebLabel = uilabel(app.InfoTab);
            app.PginawebLabel.Text = 'Página web';
            app.VolverInfo = uibutton(app.InfoTab, 'push');
            app.VolverInfo.ButtonPushedFcn = createCallbackFcn(app, @VolverInfoButtonPushed, true);
            app.VolverInfo.Text = 'Volver';
            app.BotonDeclaracion = uibutton(app.InfoTab, 'push');
            app.BotonDeclaracion.ButtonPushedFcn = createCallbackFcn(app, @BotonDeclaracionButtonPushed, true);
            app.BotonDeclaracion.Icon = fullfile(pathToMLAPP, 'Declaracion.jpeg');
            app.BotonDeclaracion.Text = '';
            app.DeclaracionLabel = uilabel(app.InfoTab);
            app.DeclaracionLabel.Text = 'Declaracion UE';
            app.Tel34632254112CorreoelectrnicoeyetechgmailcomLabel_2 = uilabel(app.InfoTab);
            app.Tel34632254112CorreoelectrnicoeyetechgmailcomLabel_2.Text = 'Tel.: +34 632 254 112   /    Correo electrónico: eyetech@gmail.com';
            app.PrincipalTab = uitab(app.TabGroup);
            app.PrincipalTab.Title = 'Principal';
            app.UIAxes = uiaxes(app.PrincipalTab);
            app.UIAxes.XTick = [];
            app.UIAxes.XTickLabel = '';
            app.UIAxes.YTick = [];
            app.UIAxes.YTickLabel = '';
            app.UIAxes.ZTick = [];
            app.VolverPrincipal = uibutton(app.PrincipalTab, 'push');
            app.VolverPrincipal.ButtonPushedFcn = createCallbackFcn(app, @VolverPrincipalButtonPushed, true);
            app.VolverPrincipal.Text = 'Volver';
            app.EYETECHTechnologiesLabel_2 = uilabel(app.PrincipalTab);
            app.EYETECHTechnologiesLabel_2.Text = 'EYETECH Technologies ';
            app.LogoPrincipal = uiimage(app.PrincipalTab);
            app.LogoPrincipal.ImageSource = 'Imagen1.png';
            app.ImagenadiagnosticarLabel = uilabel(app.PrincipalTab);
            app.ImagenadiagnosticarLabel.Text = 'Imagen a diagnosticar';
            app.CargarImagen = uibutton(app.PrincipalTab, 'push');
            app.CargarImagen.ButtonPushedFcn = createCallbackFcn(app, @CargarImagenButtonPushed, true);
            app.CargarImagen.Text = 'Cargar Imagen';
            app.CalidadImagen = uibutton(app.PrincipalTab, 'push');
            app.CalidadImagen.ButtonPushedFcn = createCallbackFcn(app, @CalidadImagenButtonPushed, true);
            app.CalidadImagen.Text = 'Mostrar calidad';
            app.Segmentacion = uibutton(app.PrincipalTab, 'push');
            app.Segmentacion.ButtonPushedFcn = createCallbackFcn(app, @SegmentacionButtonPushed, true);
            app.Segmentacion.Text = 'Segmentación';
            app.DiagnosticoBoton = uibutton(app.PrincipalTab, 'push');
            app.DiagnosticoBoton.ButtonPushedFcn = createCallbackFcn(app, @DiagnosticoBotonButtonPushed, true);
            app.DiagnosticoBoton.Text = 'Diagnóstico';
            app.CalidadPanel = uipanel(app.PrincipalTab);
            app.CalidadPanel.Title = 'Calidad';
            app.BajoContrasteLabel = uilabel(app.CalidadPanel);
            app.BajoContrasteLabel.Text = 'Bajo Contraste';
            app.DesenfoqueLabel_2 = uilabel(app.CalidadPanel);
            app.DesenfoqueLabel_2.Text = 'Desenfoque';
            app.RuidoLabel = uilabel(app.CalidadPanel);
            app.RuidoLabel.Text = 'Ruido';
            app.AltaClaridadLabel = uilabel(app.CalidadPanel);
            app.AltaClaridadLabel.Text = 'Alta Claridad';
            app.CalidadLabel = uilabel(app.CalidadPanel);
            app.CalidadLabel.Text = 'Calidad:';
            app.LamparaCalidad = uilamp(app.CalidadPanel);
            app.PorcentajeBC = uilabel(app.CalidadPanel);
            app.PorcentajeBC.Text = '%';
            app.PorcentajeD = uilabel(app.CalidadPanel);
            app.PorcentajeD.Text = '%';
            app.PorcentajeR = uilabel(app.CalidadPanel);
            app.PorcentajeR.Text = '%';
            app.PorcentajeAC = uilabel(app.CalidadPanel);
            app.PorcentajeAC.Text = '%';
            app.PorcentajeCalidad = uilabel(app.CalidadPanel);
            app.PorcentajeCalidad.Text = '%';
            app.DignsticoLabel = uilabel(app.PrincipalTab);
            app.DignsticoLabel.Text = 'Dignóstico';
            app.LamparaDiagnostico = uilamp(app.PrincipalTab);
            app.LineaAbajoInicio_4 = uilabel(app.PrincipalTab);
            app.SegmentacionTab = uitab(app.TabGroup);
            app.SegmentacionTab.Title = 'Segmentacion';
            app.UIAxes_2 = uiaxes(app.SegmentacionTab);
            app.UIAxes_2.XTick = [];
            app.UIAxes_2.XTickLabel = '';
            app.UIAxes_2.YTick = [];
            app.UIAxes_2.YTickLabel = '';
            app.UIAxes_2.ZTick = [];
            app.UIAxes_5 = uiaxes(app.SegmentacionTab);
            app.UIAxes_5.XTick = [];
            app.UIAxes_5.XTickLabel = '';
            app.UIAxes_5.YTick = [];
            app.UIAxes_5.YTickLabel = '';
            app.UIAxes_5.ZTick = [];
            app.EYETECHTechnologiesLabel_3 = uilabel(app.SegmentacionTab);
            app.EYETECHTechnologiesLabel_3.Text = 'EYETECH Technologies ';
            app.LogoPrincipal_2 = uiimage(app.SegmentacionTab);
            app.LogoPrincipal_2.ImageSource = 'Imagen1.png';
            app.ImagenadiagnosticarLabel_2 = uilabel(app.SegmentacionTab);
            app.ImagenadiagnosticarLabel_2.Text = 'Imagen a diagnosticar';
            app.Image = uiimage(app.SegmentacionTab);
            app.Image.ImageSource = 'Flecha.png';
            app.VolverSegmentacion = uibutton(app.SegmentacionTab, 'push');
            app.VolverSegmentacion.ButtonPushedFcn = createCallbackFcn(app, @VolverSegmentacionButtonPushed, true);
            app.VolverSegmentacion.Text = 'Volver';
            app.SegmentacinLabel = uilabel(app.SegmentacionTab);
            app.SegmentacinLabel.Text = 'Segmentación';
            app.Panel = uipanel(app.SegmentacionTab);
            app.DiagnsticoLampLabel = uilabel(app.Panel);
            app.DiagnsticoLampLabel.Text = 'Diagnóstico';
            app.DiagnsticoLamp = uilamp(app.Panel);
            app.CalidaddeimagenLabel = uilabel(app.Panel);
            app.CalidaddeimagenLabel.Text = 'Calidad de imagen';
            app.CalidaddeimagenLabel_2 = uilabel(app.Panel);
            app.CalidaddeimagenLabel_2.Text = 'Calidad de imagen';
            app.ROIamanoButton = uibutton(app.SegmentacionTab, 'state');
            app.ROIamanoButton.ValueChangedFcn = createCallbackFcn(app, @ROIamanoButtonValueChanged, true);
            app.ROIamanoButton.Text = 'ROI a mano';
            app.ConcenosTab = uitab(app.TabGroup);
            app.ConcenosTab.Title = 'Conócenos';
            app.FondoConocenos = uiimage(app.ConcenosTab);
            app.FondoConocenos.ImageSource = 'textura-abstracta-azul.jpg';
            app.VolverConocenos = uibutton(app.ConcenosTab, 'push');
            app.VolverConocenos.ButtonPushedFcn = createCallbackFcn(app, @VolverConocenosButtonPushed, true);
            app.VolverConocenos.Text = 'Volver';
            app.LineaArribaConocenos = uilabel(app.ConcenosTab);
            app.LineaArribaConocenos.Text = 'h';
            app.EYETECHTechnologiesAppparaeldiagnosticodeglaucomaLabel_4 = uilabel(app.ConcenosTab);
            app.EYETECHTechnologiesAppparaeldiagnosticodeglaucomaLabel_4.Text = 'EYETECH Technologies - App para el diagnostico de glaucoma';
            app.LogoConocenos = uiimage(app.ConcenosTab);
            app.LogoConocenos.ImageSource = 'Imagen1.png';
            app.LineaAbajoInicio_2 = uilabel(app.ConcenosTab);
            app.Tel34632254112CorreoelectrnicoeyetechgmailcomLabel_3 = uilabel(app.ConcenosTab);
            app.Tel34632254112CorreoelectrnicoeyetechgmailcomLabel_3.Text = 'Tel.: +34 632 254 112   /    Correo electrónico: eyetech@gmail.com';
            app.Image2 = uiimage(app.ConcenosTab);
            app.Image2.ImageSource = fullfile(pathToMLAPP, 'sobre eyetech.png');
            app.Image3 = uiimage(app.ConcenosTab);
            app.Image3.ImageSource = fullfile(pathToMLAPP, 'Alianzas.png');
            app.ParticipantesBoton = uibutton(app.ConcenosTab, 'push');
            app.ParticipantesBoton.ButtonPushedFcn = createCallbackFcn(app, @ParticipantesBotonButtonPushed, true);
            app.ParticipantesBoton.Icon = fullfile(pathToMLAPP, 'personas.jpg');
            app.ParticipantesBoton.Text = '';
            app.ParticipantesLabel = uilabel(app.ConcenosTab);
            app.ParticipantesLabel.Text = 'Participantes';
            app.ContactoBoton = uibutton(app.ConcenosTab, 'push');
            app.ContactoBoton.ButtonPushedFcn = createCallbackFcn(app, @ContactoBotonButtonPushed, true);
            app.ContactoBoton.Icon = fullfile(pathToMLAPP, 'Contacto.jpeg');
            app.ContactoBoton.Text = '';
            app.ContactoLabel = uilabel(app.ConcenosTab);
            app.ContactoLabel.Text = 'Contacto';
            app.NosotrosTab = uitab(app.TabGroup);
            app.NosotrosTab.Title = 'Nosotros';
            app.FondoConocenos_2 = uiimage(app.NosotrosTab);
            app.FondoConocenos_2.ImageSource = 'textura-abstracta-azul.jpg';
            app.LineaArribaConocenos_2 = uilabel(app.NosotrosTab);
            app.LineaArribaConocenos_2.Text = 'h';
            app.EYETECHTechnologiesAppparaeldiagnosticodeglaucomaLabel_5 = uilabel(app.NosotrosTab);
            app.EYETECHTechnologiesAppparaeldiagnosticodeglaucomaLabel_5.Text = 'EYETECH Technologies - App para el diagnostico de glaucoma';
            app.LogoConocenos_2 = uiimage(app.NosotrosTab);
            app.LogoConocenos_2.ImageSource = 'Imagen1.png';
            app.LineaAbajoInicio_5 = uilabel(app.NosotrosTab);
            app.Tel34632254112CorreoelectrnicoeyetechgmailcomLabel_4 = uilabel(app.NosotrosTab);
            app.Tel34632254112CorreoelectrnicoeyetechgmailcomLabel_4.Text = 'Tel.: +34 632 254 112   /    Correo electrónico: eyetech@gmail.com';
            app.VolverNosotros = uibutton(app.NosotrosTab, 'push');
            app.VolverNosotros.ButtonPushedFcn = createCallbackFcn(app, @VolverNosotrosButtonPushed, true);
            app.VolverNosotros.Text = 'Volver';
            app.Image4 = uiimage(app.NosotrosTab);
            app.Image4.ImageSource = fullfile(pathToMLAPP, 'Participantes.png');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = appDiagnostico

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end